classdef MatlabMidterm < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure     matlab.ui.Figure
        Button_4     matlab.ui.control.Button
        ResetButton  matlab.ui.control.Button
        CloseButton  matlab.ui.control.Button
        STOPButton   matlab.ui.control.Button
        GridAxes     matlab.ui.control.UIAxes
    end

    
    properties (Access = public)
    end
    
    properties (Access = private)
        jetCoordinates
        jetDirections
        polyJet1
        polyJet2
        bullets
        run = 1
        jetVelocity = [0 0]
        jetAcceleration = 1.5
        airDecrease  = 0.9
    end
    
    methods (Access = private)


        function jetPoints = jetPoints(~,pos,dir)
                 switch dir
                    case 1
                        jetPoints = [pos(1)-0.5 pos(2)-0.5
                                     pos(1)-0.5 pos(2)+1.5
                                     pos(1)+0.5 pos(2)+1.5
                                     pos(1)+0.5 pos(2)-0.5
                                     pos(1)+1.5 pos(2)-0.5
                                     pos(1)+1.5 pos(2)-1.5
                                     pos(1)-1.5 pos(2)-1.5
                                     pos(1)-1.5 pos(2)-0.5];
                    case 2
                        jetPoints = [pos(1)-0.5 pos(2)+0.5
                                     pos(1)+1.5 pos(2)+0.5
                                     pos(1)+1.5 pos(2)-0.5
                                     pos(1)-0.5 pos(2)-0.5
                                     pos(1)-0.5 pos(2)-1.5
                                     pos(1)-1.5 pos(2)-1.5
                                     pos(1)-1.5 pos(2)+1.5
                                     pos(1)-0.5 pos(2)+1.5];
                    case 3
                        jetPoints = [pos(1)+0.5 pos(2)+0.5
                                     pos(1)+0.5 pos(2)-1.5
                                     pos(1)-0.5 pos(2)-1.5
                                     pos(1)-0.5 pos(2)+0.5
                                     pos(1)-1.5 pos(2)+0.5
                                     pos(1)-1.5 pos(2)+1.5
                                     pos(1)+1.5 pos(2)+1.5
                                     pos(1)+1.5 pos(2)+0.5  ];
                    case 4  
                        jetPoints = [pos(1)+0.5 pos(2)-0.5
                                     pos(1)-1.5 pos(2)-0.5
                                     pos(1)-1.5 pos(2)+0.5
                                     pos(1)+0.5 pos(2)+0.5
                                     pos(1)+0.5 pos(2)+1.5
                                     pos(1)+1.5 pos(2)+1.5
                                     pos(1)+1.5 pos(2)-1.5
                                     pos(1)+0.5 pos(2)-1.5];
                    otherwise
    
                end
        end
        
        function polyJet = jetCreate(app,pos,dir)
            
                polyPoints = jetPoints(app,pos,dir);
                polyJet = polyshape(polyPoints(:,1),polyPoints(:,2));
        end


        function bulletPoly = bulletCreate(~,pos)
            bulletPoints = [pos(1)-0.5 pos(2)-0.5
                            pos(1)+0.5 pos(2)-0.5
                            pos(1)+0.5 pos(2)+0.5
                            pos(1)-0.5 pos(2)+0.5];
            bulletPoly = polyshape(bulletPoints(:,1),bulletPoints(:,2));

        end
        
        function gridUpdate(app)
            cla(app.GridAxes);
            hold(app.GridAxes, "on");

            for i = 1:2
                if app.jetCoordinates(i,1) <= 0
                    app.jetCoordinates(i,1) = 55;
                end
                if app.jetCoordinates(i,1) > 55
                    app.jetCoordinates(i,1) = 0;
                end
                if app.jetCoordinates(i,2) <= 0
                    app.jetCoordinates(i,2) = 45;
                end
                if app.jetCoordinates(i,2) > 45
                    app.jetCoordinates(i,2) = 0;
                end
            end

%             for i = 1:2
%                 switch i
%                     case 1
%                         jetColor = "Red";
%                         jetExtra = jetPoints(app,app.jetCoordinates(1,:),app.jetDirections(1));
%                     case 2
%                         jetColor = "Green";
%                         jetExtra = jetPoints(app,app.jetCoordinates(2,:),app.jetDirections(2));
%                 end
% 
%                 if app.jetCoordinates(i,1)<1.5 || app.jetCoordinates(i,1) >53.5 || app.jetCoordinates(i,2) <1.5 || app.jetCoordinates(i,2) >43.5 
%                  
%                     plot(app.GridAxes,polyshape(jetExtra(:,1)+55,jetExtra(:,2)   ),"FaceColor",jetColor,"FaceAlpha",1,"Edgecolor",jetColor);
%                     plot(app.GridAxes,polyshape(jetExtra(:,1)-55,jetExtra(:,2)   ),"FaceColor",jetColor,"FaceAlpha",1,"Edgecolor",jetColor);
%                     plot(app.GridAxes,polyshape(jetExtra(:,1)   ,jetExtra(:,2)+45),"FaceColor",jetColor,"FaceAlpha",1,"Edgecolor",jetColor);
%                     plot(app.GridAxes,polyshape(jetExtra(:,1)   ,jetExtra(:,2)-45),"FaceColor",jetColor,"FaceAlpha",1,"Edgecolor",jetColor);
%                     plot(app.GridAxes,polyshape(jetExtra(:,1)+55,jetExtra(:,2)+45),"FaceColor",jetColor,"FaceAlpha",1,"Edgecolor",jetColor);
%                     plot(app.GridAxes,polyshape(jetExtra(:,1)-55,jetExtra(:,2)-45),"FaceColor",jetColor,"FaceAlpha",1,"Edgecolor",jetColor);
%                     plot(app.GridAxes,polyshape(jetExtra(:,1)-55,jetExtra(:,2)+45),"FaceColor",jetColor,"FaceAlpha",1,"Edgecolor",jetColor);
%                     plot(app.GridAxes,polyshape(jetExtra(:,1)+55,jetExtra(:,2)-45),"FaceColor",jetColor,"FaceAlpha",1,"Edgecolor",jetColor);   
%                 end
% 
%             end
            
            plot(app.GridAxes,app.polyJet1,"FaceColor","red","FaceAlpha",1,"Edgecolor","Red");
            plot(app.GridAxes,app.polyJet2,"FaceColor","green","FaceAlpha",1,"Edgecolor","Green");
            

            bulletCount = height(app.bullets);
            for i = 1:bulletCount
                bulletPoly = bulletCreate(app,app.bullets(i,2:3));
                switch app.bullets(i,1)
                    case 1
                        plot(app.GridAxes,bulletPoly,"FaceColor","Red");
                    case 2
                        plot(app.GridAxes,bulletPoly,"FaceColor","Green");
                    otherwise
                end
            end
            
            hold(app.GridAxes, "off");
            
        end
        
        function jetMove(app)
            for jetNumber = 1:2
                    switch app.jetDirections(jetNumber)
                        case 1
                            app.jetCoordinates(jetNumber,2) = app.jetCoordinates(jetNumber,2) + app.jetVelocity(jetNumber);
                        case 3
                            app.jetCoordinates(jetNumber,2) = app.jetCoordinates(jetNumber,2) - app.jetVelocity(jetNumber);
                        case 2
                            app.jetCoordinates(jetNumber,1) = app.jetCoordinates(jetNumber,1) + app.jetVelocity(jetNumber);
                        case 4
                            app.jetCoordinates(jetNumber,1) = app.jetCoordinates(jetNumber,1) - app.jetVelocity(jetNumber);
                    end
            end
        end
        
        function fire(app,player)
            bulletDirection = app.jetDirections(player);
            switch bulletDirection
                case 1
                    bulletCoordinates = [app.jetCoordinates(player,1) app.jetCoordinates(player,2)+2];
                case 2
                    bulletCoordinates = [app.jetCoordinates(player,1)+2 app.jetCoordinates(player,2)];
                case 3
                    bulletCoordinates = [app.jetCoordinates(player,1) app.jetCoordinates(player,2)-2];
                case 4
                    bulletCoordinates = [app.jetCoordinates(player,1)-2 app.jetCoordinates(player,2)];
                otherwise
            end
            app.bullets = [app.bullets; player bulletCoordinates(1) bulletCoordinates(2) bulletDirection];
        end
        
        function game(app)
            while app.run

                for i = 1:2
                    app.jetVelocity(i) = app.jetVelocity(i)*app.airDecrease;
                    if app.jetVelocity(i) < 0.1
                        app.jetVelocity(i) = 0;
                    end

                bulletPosition(app);
                jetMove(app);
                app.polyJet1 = jetCreate(app,app.jetCoordinates(1,:),app.jetDirections(1));
                app.polyJet2 = jetCreate(app,app.jetCoordinates(2,:),app.jetDirections(2));
                collisionCheck(app);
                pause(0.0125);
                end

                
                gridUpdate(app);
                
            end
        end
        
        function bulletPosition(app)
            bulletCount = height(app.bullets);
                for i = 1:bulletCount
                    switch app.bullets(i,4)
                        case 1
                            app.bullets(i,3) = app.bullets (i,3)+1;
                        case 2
                            app.bullets(i,2) = app.bullets (i,2)+1;
                        case 3
                            app.bullets(i,3) = app.bullets (i,3)-1;
                        case 4
                            app.bullets(i,2) = app.bullets (i,2)-1;
                    end
                end

                i = 1;
                while i <= height(app.bullets)
                    if app.bullets(i,2) <  0 || app.bullets(i,2) > 55
                        app.bullets(i,:) = [];
                        i = i-1;
                    else
                        if app.bullets(i,3) <  0 || app.bullets(i,3) > 45
                        app.bullets(i,:) = [];
                        i = i-1;
                        end
                    end
                    i = i+1;
                end
        end
        
        
        function collisionCheck(app)
            jetCollision = intersect(app.polyJet1, app.polyJet2);
            if jetCollision.NumRegions >= 1
                reset(app);
            end
            
            for i = 1:height(app.bullets)
                bulletPoly = bulletCreate(app,app.bullets(i,2:3));
                
                    bullet_jet1Collission = intersect(bulletPoly,app.polyJet1);
                    bullet_jet2Collission = intersect(bulletPoly,app.polyJet2);

                    if bullet_jet1Collission.NumRegions >= 1
                        reset(app);
                    end

                    if bullet_jet2Collission.NumRegions >= 1
                        reset(app);
                    end
                
            end
            
%             hit_bullet1 = intersect(j2_pos, j1_bullet, "rows");
%             if length(hit_bullet1) >= 1
%                 app.score_j1 = app.score_j1 + 1;
%             end
%             
%             hit_bullet2 = intersect(j1_pos, j2_bullet, "rows");
%             if length(hit_bullet2) >= 1
%                 app.score_j2 = app.score_j2 + 1;
%             end
%             
%             friendly_fire1 = intersect(j1_pos, j1_bullet, "rows");
%             if length(friendly_fire1) >= 1
%                 app.score_j1 = app.score_j1 - 1;
%             end
%             
%             friendly_fire2 = intersect(j2_pos, j2_bullet, "rows");
%             if length(friendly_fire2) >= 1
%                 app.score_j2 = app.score_j2 - 1;
%             end
        end
        
        function reset(app)
            app.run = 0;
            app.jetVelocity = [0 0];
            app.jetAcceleration = 2;
            app.airDecrease  = 0.95;
            app.bullets = [];
            app.jetCoordinates = [2.5 2.5; 52.5 42.5];
            app.jetDirections = [1 3];
            gridUpdate(app);
            app.run = 1;
            game(app);
        end
    end
  
    
    methods (Access = public)
        
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.jetCoordinates = [2.5 2.5; 52.5 42.5];
            app.jetDirections = [1 3];
            app.polyJet1 = jetCreate(app,app.jetCoordinates(1,:),app.jetDirections(1));
            app.polyJet2 = jetCreate(app,app.jetCoordinates(2,:),app.jetDirections(2));
            gridUpdate(app);
            game(app);
        end

        % Key press function: UIFigure
        function KeyPressFcn(app, event)
            key = event.Key;
            switch key
                case "uparrow"
                    if app.jetVelocity(2) < 0.5
                        app.jetVelocity(2) = 0.7;
                    else
                    app.jetVelocity(2) = app.jetAcceleration + ((0.5)^(app.jetAcceleration))*log2(app.jetVelocity(2));
                    end
                case "w"
                    if app.jetVelocity(1) < 0.5
                        app.jetVelocity(1) = 0.7;
                    else
                    app.jetVelocity(1) = app.jetAcceleration + ((0.5)^(app.jetAcceleration))*log2(app.jetVelocity(1));
                    end
                case "d"
                    app.jetDirections(1) = app.jetDirections(1) + 1;
                case "rightarrow"
                    app.jetDirections(2) = app.jetDirections(2) + 1;
                case "a"
                    app.jetDirections(1) = app.jetDirections(1) - 1;
                case "leftarrow"
                    app.jetDirections(2) = app.jetDirections(2) - 1;  
                case "v"
                    fire(app,1);
                case "m"
                    fire(app,2);
                case "downarrow"
                    app.jetVelocity(2) = app.jetVelocity(2)+0.5*log2(app.jetVelocity(2)/app.jetAcceleration);
                    if app.jetVelocity(2) < 0
                        app.jetVelocity(2) = 0;
                    end
                case "s"
                    app.jetVelocity(1) = app.jetVelocity(1)+0.5*log2(app.jetVelocity(1)/app.jetAcceleration);
                    if app.jetVelocity(1) < 0
                        app.jetVelocity(1) = 0;
                    end
                otherwise
            end
            for i = 1:2
            if app.jetDirections(i) == 0
                app.jetDirections(i) = 4;
            end
            if app.jetDirections(i) == 5
                app.jetDirections(i) = 1;
            end
            end
            
            gridUpdate(app);
        end

        % Button pushed function: STOPButton
        function STOPButtonPushed(app, event)
            app.run = 0;
        end

        % Button pushed function: CloseButton
        function CloseButtonPushed(app, event)
            close(app.UIFigure);
        end

        % Button pushed function: ResetButton
        function ResetButtonPushed(app, event)
            reset(app);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.KeyPressFcn = createCallbackFcn(app, @KeyPressFcn, true);
            app.GridAxes = uiaxes(app.UIFigure);
            zlabel(app.GridAxes, 'Z')
            app.GridAxes.XLim = [0 55];
            app.GridAxes.YLim = [0 45];
            app.GridAxes.XTick = [1 2 3 4 5 6 7 8 9 10 11 12 13 14 15 16 17 18 19 20 21 22 23 24 25 26 27 28 29 30 31 32 33 34 35 36 37 38 39 40 41 42 43 44 45 46 47 48 49 50 51 52 53 54];
            app.GridAxes.XTickLabel = '';
            app.GridAxes.YTick = [1 2 3 4 5 6 7 8 9 10 11 12 13 14 15 16 17 18 19 20 21 22 23 24 25 26 27 28 29 30 31 32 33 34 35 36 37 38 39 40 41 42 43 44];
            app.GridAxes.YTickLabel = '';
            app.STOPButton = uibutton(app.UIFigure, 'push');
            app.STOPButton.ButtonPushedFcn = createCallbackFcn(app, @STOPButtonPushed, true);
            app.STOPButton.Text = 'STOP';
            app.CloseButton = uibutton(app.UIFigure, 'push');
            app.CloseButton.ButtonPushedFcn = createCallbackFcn(app, @CloseButtonPushed, true);
            app.CloseButton.Text = 'Close';
            app.ResetButton = uibutton(app.UIFigure, 'push');
            app.ResetButton.ButtonPushedFcn = createCallbackFcn(app, @ResetButtonPushed, true);
            app.ResetButton.Text = 'Reset';
            app.Button_4 = uibutton(app.UIFigure, 'push');
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = MatlabMidterm

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end