classdef MatlabMidtermV2 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                     matlab.ui.Figure
        Player2JetSelectButtonGroup  matlab.ui.container.ButtonGroup
        ImmobileButton_2             matlab.ui.control.RadioButton
        SolarButton_2                matlab.ui.control.RadioButton
        NormalButton_2               matlab.ui.control.RadioButton
        Player1JetSelectButtonGroup  matlab.ui.container.ButtonGroup
        ImmobileButton               matlab.ui.control.RadioButton
        SolarButton                  matlab.ui.control.RadioButton
        NormalButton                 matlab.ui.control.RadioButton
        DifficultyDropDown           matlab.ui.control.DropDown
        DifficultyDropDownLabel      matlab.ui.control.Label
        VLabel                       matlab.ui.control.Label
        LeftRightLabel               matlab.ui.control.Label
        MLabel                       matlab.ui.control.Label
        ADLabel                      matlab.ui.control.Label
        DownLabel                    matlab.ui.control.Label
        SLabel                       matlab.ui.control.Label
        UpLabel                      matlab.ui.control.Label
        WLabel                       matlab.ui.control.Label
        FireLabel                    matlab.ui.control.Label
        RotateLabel                  matlab.ui.control.Label
        DecelerateLabel              matlab.ui.control.Label
        AccelerateLabel              matlab.ui.control.Label
        Player2EditField_3           matlab.ui.control.NumericEditField
        Player2EditField_3Label      matlab.ui.control.Label
        Player1EditField_3           matlab.ui.control.NumericEditField
        Player1EditField_3Label      matlab.ui.control.Label
        FuelLabel                    matlab.ui.control.Label
        Player2EditField_2           matlab.ui.control.NumericEditField
        Player2EditField_2Label      matlab.ui.control.Label
        BulletsLabel                 matlab.ui.control.Label
        Player1EditField_2           matlab.ui.control.NumericEditField
        Player1EditField_2Label      matlab.ui.control.Label
        ScoreLabel                   matlab.ui.control.Label
        TextArea                     matlab.ui.control.TextArea
        TimeEditField                matlab.ui.control.NumericEditField
        TimeEditFieldLabel           matlab.ui.control.Label
        Player2EditField             matlab.ui.control.NumericEditField
        Player2EditFieldLabel        matlab.ui.control.Label
        Player1EditField             matlab.ui.control.NumericEditField
        Player1EditFieldLabel        matlab.ui.control.Label
        STARTButton                  matlab.ui.control.Button
        RESETButton                  matlab.ui.control.Button
        CLOSEButton                  matlab.ui.control.Button
        STOPButton                   matlab.ui.control.Button
        GridAxes                     matlab.ui.control.UIAxes
    end

    
    properties (Access = public)
    end
    
    properties (Access = private)                                           % Here all the properties are set which are used in the game
        jetCoordinates                                                      % Coordinates of both jets. jetCoordinates = [ xJet1 yJet1; xJet2 yJet2 ]
        jetDirections                                                       % Directions of both jets. jetDirections = [ jet1Direction jet2Direction ]
        polyJet1                                                            % Polyshape object of jet 1
        polyJet2                                                            % Polyshape object of jet 2
        bullets                                                             % Array with the present bullets. bullets = [ playerBullet BulletX BulletY BulletDirection ]
        run = 0                                                             % Boolean run value. 1 = game is running, 0 = game is not running
        jetVelocity = [0 0]                                                 % The velocity of both jets. [ velJet1 velJet2 ]
        jetAcceleration = 1.5                                               % Acceleration of the jets
        airDecrease  = 0.9                                                  % airfriction coefficient of the jets
        timer                                                               % Timer object used for the gameTimer
    end
    
    methods (Access = private)

        function jetPoints = jetPoints(~,pos,dir)                           % This function creates a matrix with coordinates of edgepoints of the jet polyshape.
                 switch dir                                                 % This dependends on the jet position and direction.
                    case 1
                        jetPoints = [pos(1)-0.5 pos(2)-0.5
                                     pos(1)-0.5 pos(2)+1.5
                                     pos(1)+0.5 pos(2)+1.5
                                     pos(1)+0.5 pos(2)-0.5
                                     pos(1)+1.5 pos(2)-0.5
                                     pos(1)+1.5 pos(2)-1.5
                                     pos(1)-1.5 pos(2)-1.5
                                     pos(1)-1.5 pos(2)-0.5];
                    case 2
                        jetPoints = [pos(1)-0.5 pos(2)+0.5
                                     pos(1)+1.5 pos(2)+0.5
                                     pos(1)+1.5 pos(2)-0.5
                                     pos(1)-0.5 pos(2)-0.5
                                     pos(1)-0.5 pos(2)-1.5
                                     pos(1)-1.5 pos(2)-1.5
                                     pos(1)-1.5 pos(2)+1.5
                                     pos(1)-0.5 pos(2)+1.5];
                    case 3
                        jetPoints = [pos(1)+0.5 pos(2)+0.5
                                     pos(1)+0.5 pos(2)-1.5
                                     pos(1)-0.5 pos(2)-1.5
                                     pos(1)-0.5 pos(2)+0.5
                                     pos(1)-1.5 pos(2)+0.5
                                     pos(1)-1.5 pos(2)+1.5
                                     pos(1)+1.5 pos(2)+1.5
                                     pos(1)+1.5 pos(2)+0.5  ];
                    case 4  
                        jetPoints = [pos(1)+0.5 pos(2)-0.5
                                     pos(1)-1.5 pos(2)-0.5
                                     pos(1)-1.5 pos(2)+0.5
                                     pos(1)+0.5 pos(2)+0.5
                                     pos(1)+0.5 pos(2)+1.5
                                     pos(1)+1.5 pos(2)+1.5
                                     pos(1)+1.5 pos(2)-1.5
                                     pos(1)+0.5 pos(2)-1.5];
                    otherwise
    
                end
        end
        
        function polyJet = jetCreate(app,pos,dir)                           % Create a polyshape object from the position and direction of a jet given.
            
                polyPoints = jetPoints(app,pos,dir);
                polyJet = polyshape(polyPoints(:,1),polyPoints(:,2));
        end

        function bulletPoly = bulletCreate(~,pos)                           % Create the polyshape of a bullet depending on its position.
            bulletPoints = [pos(1)-0.5 pos(2)-0.5
                            pos(1)+0.5 pos(2)-0.5
                            pos(1)+0.5 pos(2)+0.5
                            pos(1)-0.5 pos(2)+0.5];
            bulletPoly = polyshape(bulletPoints(:,1),bulletPoints(:,2));

        end
        
        function gridUpdate(app)                                            % Refresh the grid with new positions
            cla(app.GridAxes);                                              % Clear the grid
            hold(app.GridAxes, "on");                                       % Hold on to plot multiple objects

            for i = 1:2                                                     % Change the position if a jet moves out of the grid
                if app.jetCoordinates(i,1) <= 0
                    app.jetCoordinates(i,1) = 55;
                end
                if app.jetCoordinates(i,1) > 55
                    app.jetCoordinates(i,1) = 0;
                end
                if app.jetCoordinates(i,2) <= 0
                    app.jetCoordinates(i,2) = 45;
                end
                if app.jetCoordinates(i,2) > 45
                    app.jetCoordinates(i,2) = 0;
                end
            end

            for i = 1:2                                                     % In this for loop multiple extra jets are plotted if a jet reaches the edges, to let it move smoothly to the otherside
                switch i
                    case 1
                        jetColor = "Red";
                        jetExtra = jetPoints(app,app.jetCoordinates(1,:),app.jetDirections(1));
                    case 2
                        jetColor = "Green";
                        jetExtra = jetPoints(app,app.jetCoordinates(2,:),app.jetDirections(2));
                end

                if app.jetCoordinates(i,1)<1.5 || app.jetCoordinates(i,1) >53.5 || app.jetCoordinates(i,2) <1.5 || app.jetCoordinates(i,2) >43.5 
                 
                    plot(app.GridAxes,polyshape(jetExtra(:,1)+55,jetExtra(:,2)   ),"FaceColor",jetColor,"FaceAlpha",1,"Edgecolor",jetColor);
                    plot(app.GridAxes,polyshape(jetExtra(:,1)-55,jetExtra(:,2)   ),"FaceColor",jetColor,"FaceAlpha",1,"Edgecolor",jetColor);
                    plot(app.GridAxes,polyshape(jetExtra(:,1)   ,jetExtra(:,2)+45),"FaceColor",jetColor,"FaceAlpha",1,"Edgecolor",jetColor);
                    plot(app.GridAxes,polyshape(jetExtra(:,1)   ,jetExtra(:,2)-45),"FaceColor",jetColor,"FaceAlpha",1,"Edgecolor",jetColor);
                    plot(app.GridAxes,polyshape(jetExtra(:,1)+55,jetExtra(:,2)+45),"FaceColor",jetColor,"FaceAlpha",1,"Edgecolor",jetColor);
                    plot(app.GridAxes,polyshape(jetExtra(:,1)-55,jetExtra(:,2)-45),"FaceColor",jetColor,"FaceAlpha",1,"Edgecolor",jetColor);
                    plot(app.GridAxes,polyshape(jetExtra(:,1)-55,jetExtra(:,2)+45),"FaceColor",jetColor,"FaceAlpha",1,"Edgecolor",jetColor);
                    plot(app.GridAxes,polyshape(jetExtra(:,1)+55,jetExtra(:,2)-45),"FaceColor",jetColor,"FaceAlpha",1,"Edgecolor",jetColor);   
                end

            end
            
            plot(app.GridAxes,app.polyJet1,"FaceColor","red","FaceAlpha",1,"Edgecolor","Red");         % Plot the polyshape of Jet1
            plot(app.GridAxes,app.polyJet2,"FaceColor","green","FaceAlpha",1,"Edgecolor","Green");     % Plot the polyshape of Jet2
            

            bulletCount = height(app.bullets);
            for i = 1:bulletCount                                           % Plot all the polyshapes of the bullets, with the color depending on the player they belong to
                bulletPoly = bulletCreate(app,app.bullets(i,2:3));
                switch app.bullets(i,1)
                    case 1
                        plot(app.GridAxes,bulletPoly,"FaceColor","Red");
                    case 2
                        plot(app.GridAxes,bulletPoly,"FaceColor","Green");
                    otherwise
                end
            end
            
            hold(app.GridAxes, "off");

            if app.Player1EditField_3.Value <= 0 || app.Player2EditField_3.Value <= 0   % If the fuel is empty for one of the players, end the game
                app.run = 0;
            end
            
            if toc(app.timer) > 30                                          % If the timer has ran out, end the game
                app.TimeEditField.Value = 0;
                app.run = 0;
                return;
            end

            app.TimeEditField.Value = 30 - toc(app.timer);              % Update the field of the game timer

            if app.SolarButton.Value == 1
                app.Player1EditField_3.Value = app.Player1EditField_3.Value + rand/5;
            end

            if app.SolarButton_2.Value == 1
                app.Player2EditField_3.Value = app.Player2EditField_3.Value + rand/5;
            end
            
        end
        
        function jetMove(app)                                               % Update the position of the jets depending on their current velocity
            for jetNumber = 1:2
                    switch app.jetDirections(jetNumber)
                        case 1
                            app.jetCoordinates(jetNumber,2) = app.jetCoordinates(jetNumber,2) + app.jetVelocity(jetNumber);
                        case 3
                            app.jetCoordinates(jetNumber,2) = app.jetCoordinates(jetNumber,2) - app.jetVelocity(jetNumber);
                        case 2
                            app.jetCoordinates(jetNumber,1) = app.jetCoordinates(jetNumber,1) + app.jetVelocity(jetNumber);
                        case 4
                            app.jetCoordinates(jetNumber,1) = app.jetCoordinates(jetNumber,1) - app.jetVelocity(jetNumber);
                    end
            end
        end
        
        function fire(app,player)                                           % Create a new bullet with its position being fired, the player firing and the direction it is fired in.
            bulletDirection = app.jetDirections(player);
            switch bulletDirection
                case 1
                    bulletCoordinates = [app.jetCoordinates(player,1) app.jetCoordinates(player,2)+2];
                case 2
                    bulletCoordinates = [app.jetCoordinates(player,1)+2 app.jetCoordinates(player,2)];
                case 3
                    bulletCoordinates = [app.jetCoordinates(player,1) app.jetCoordinates(player,2)-2];
                case 4
                    bulletCoordinates = [app.jetCoordinates(player,1)-2 app.jetCoordinates(player,2)];
                otherwise
            end
            app.bullets = [app.bullets; player bulletCoordinates(1) bulletCoordinates(2) bulletDirection];
        end
        
        function game(app)                                                  % The main loop when the app is running
            while app.run

                for i = 1:2                                                 % Decrease the velocity of the jets every iteration with the air friction
                    app.jetVelocity(i) = app.jetVelocity(i)*app.airDecrease;
                    if app.jetVelocity(i) < 0.1
                        app.jetVelocity(i) = 0;
                    end
                end

                bulletPosition(app);                                        % Update all the positions of the bullets
                jetMove(app);                                               % Update the positions of the jets
                app.polyJet1 = jetCreate(app,app.jetCoordinates(1,:),app.jetDirections(1));
                app.polyJet2 = jetCreate(app,app.jetCoordinates(2,:),app.jetDirections(2));
                collisionCheck(app);                                        % Check for collisions between bullets and/or jets

                gridUpdate(app);                                            % Update the grid
                pause(0.03);                                                % Refresh rate of the grid

            end

            if app.Player1EditField_3.Value ~= 0 && app.Player2EditField_3.Value ~= 0       % Show the game results depending on time, bullet hits and fuel
                cla(app.GridAxes);
                if app.Player1EditField.Value > app.Player2EditField.Value
                    msgbox(strcat('Game Over: Player 1 WINS!'));
                end
                if app.Player2EditField.Value > app.Player1EditField.Value
                    msgbox(strcat('Game Over: Player 2 WINS!'));
                end
                if app.Player1EditField.Value == app.Player2EditField.Value
                    if app.Player1EditField_3.Value > app.Player2EditField_3.Value
                        msgbox(strcat('Game Over: Player 1 WINS!'));
                    end
                    if app.Player1EditField_3.Value < app.Player2EditField_3.Value
                        msgbox(strcat('Game Over: Player 2 WINS!'));
                    end
                    if app.Player1EditField_3.Value == app.Player2EditField_3.Value
                        msgbox(strcat('Game Over: DRAW'));
                    end
                end
            elseif app.Player1EditField_3.Value <= 0
                cla(app.GridAxes);
                msgbox(strcat('Game Over: Player 2 WINS!'));
            elseif app.Player2EditField_3.Value <= 0
                cla(app.GridAxes);
                msgbox(strcat('Game Over: Player 1 WINS!'));
            end
        end
        
        function bulletPosition(app)                                        % Update the position of the bullets
            bulletCount = height(app.bullets);
                for i = 1:bulletCount
                    switch app.bullets(i,4)
                        case 1
                            app.bullets(i,3) = app.bullets (i,3)+1;
                        case 2
                            app.bullets(i,2) = app.bullets (i,2)+1;
                        case 3
                            app.bullets(i,3) = app.bullets (i,3)-1;
                        case 4
                            app.bullets(i,2) = app.bullets (i,2)-1;
                    end
                end

                i = 1;
                while i <= height(app.bullets)                              % Remove the bullets if they move out of the grid
                    if app.bullets(i,2) <  0 || app.bullets(i,2) > 55
                        app.bullets(i,:) = [];
                        i = i-1;
                    else
                        if app.bullets(i,3) <  0 || app.bullets(i,3) > 45
                        app.bullets(i,:) = [];
                        i = i-1;
                        end
                    end
                    i = i+1;
                end
        end
        
        function collisionCheck(app)                                        % Check for collisions between objects
            jetCollision = intersect(app.polyJet1, app.polyJet2);
            if jetCollision.NumRegions >= 1                                 % Reset the positions en velocities of the jets when they hit eachother
                app.jetVelocity = [0 0];
                app.bullets = [];                                           % Remove all the bullets from the playing field
                app.jetCoordinates = [2.5 2.5; 52.5 42.5];
                app.jetDirections = [1 3];
                gridUpdate(app);
            else
            
                i = 1;
            while i <= height(app.bullets)
                bulletPoly = bulletCreate(app,app.bullets(i,2:3));
                    
                    bullet_jet1Collission = intersect(bulletPoly,app.polyJet1);         % Check for hits by bullets
                    bullet_jet2Collission = intersect(bulletPoly,app.polyJet2);
    
                    if bullet_jet1Collission.NumRegions >= 1                            % If a jet gets hit by its own bullet, score -1
                        switch app.bullets(i,1)                                         % If a jet hits the other jet, score + 1
                        case 1
                            app.Player1EditField.Value = app.Player1EditField.Value - 1;
                        case 2
                            app.Player2EditField.Value = app.Player2EditField.Value + 1;
                        end
                        gridUpdate(app);
                        app.bullets(i,:) = [];                                          % Remove the bullet that hits the jet
                        i = i - 1;
                    end

                    if bullet_jet2Collission.NumRegions >= 1
                        switch app.bullets(i,1)
                        case 1
                            app.Player1EditField.Value = app.Player1EditField.Value + 1;
                        case 2
                            app.Player2EditField.Value = app.Player2EditField.Value - 1;
                        end
                        gridUpdate(app);
                        app.bullets(i,:) = [];
                        i = i - 1;
                    end
                i = i + 1;
            end
            end
        end
        
        function reset(app)                                                 % Reset the game
            app.run = 0;
            app.Player1EditField.Value = 0;
            app.Player2EditField.Value = 0;
            app.Player1EditField_2.Value = 10;
            app.Player2EditField_2.Value = 10;
            app.Player1EditField_3.Value = 50;
            app.Player2EditField_3.Value = 50;
            if app.SolarButton.Value == 1
                app.Player1EditField_3.Value = 25;
                app.Player1EditField_2.Value = 10;
            end
            if app.SolarButton_2.Value == 1
                app.Player2EditField_3.Value = 25;
                app.Player2EditField_2.Value = 10;
            end
            if app.ImmobileButton.Value == 1
                app.Player1EditField_3.Value = 1;
                app.Player1EditField_2.Value = 99;
            end
            if app.ImmobileButton_2.Value == 1
                app.Player2EditField_3.Value = 1;
                app.Player2EditField_2.Value = 99;
            end
            app.TimeEditField.Value = 30;
            cla(app.GridAxes);
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            gridUpdate(app);
            app.run = 0;
            game(app);
        end

        % Key press function: UIFigure
        function KeyPressFcn(app, event)
            key = event.Key;
            switch key                                                                          % Do different actions depending on the input of the users keyboard
                case "uparrow"
                    if strcmp(app.DifficultyDropDown.Value,'Hard Mode')                         % For the arrow up and w accelerate the jets
                        app.Player2EditField_3.Value = app.Player2EditField_3.Value - 1;        % Decrease the amount of fuel depending on the mode
                    elseif strcmp(app.DifficultyDropDown.Value,'Very Hard Mode')                
                        app.Player2EditField_3.Value = app.Player2EditField_3.Value - 2;
                    end
                    if app.Player2EditField_3.Value < 0
                        app.Player2EditField_3.Value = 0;
                    end
                    if app.ImmobileButton_2.Value ~= 1
                        if app.jetVelocity(2) < 0.5
                            app.jetVelocity(2) = 0.7;
                        else
                        app.jetVelocity(2) = app.jetAcceleration + ((0.5)^(app.jetAcceleration))*log2(app.jetVelocity(2));
                        end
                    end
                case "w"
                    if strcmp(app.DifficultyDropDown.Value,'Hard Mode') 
                        app.Player1EditField_3.Value = app.Player1EditField_3.Value - 1;
                    elseif strcmp(app.DifficultyDropDown.Value,'Very Hard Mode')
                        app.Player1EditField_3.Value = app.Player1EditField_3.Value - 2;
                    end
                    if app.Player2EditField_3.Value < 0
                        app.Player2EditField_3.Value = 0;
                    end
                    if app.ImmobileButton.Value ~= 1
                        if app.jetVelocity(1) < 0.5
                            app.jetVelocity(1) = 0.7;
                        else
                        app.jetVelocity(1) = app.jetAcceleration + ((0.5)^(app.jetAcceleration))*log2(app.jetVelocity(1));
                        end
                    end
                case "d"
                    app.jetDirections(1) = app.jetDirections(1) + 1;         % Change the direction of the jets
                case "rightarrow"
                    app.jetDirections(2) = app.jetDirections(2) + 1;
                case "a"
                    app.jetDirections(1) = app.jetDirections(1) - 1;
                case "leftarrow"
                    app.jetDirections(2) = app.jetDirections(2) - 1;  
                case "v"                                                    % Fire a bullet and decrease your ammunition with 1
                    if app.Player1EditField_2.Value > 0
                        fire(app,1);
                        app.Player1EditField_2.Value = app.Player1EditField_2.Value - 1;
                    end
                case "m"
                    if app.Player2EditField_2.Value > 0
                        fire(app,2);
                        app.Player2EditField_2.Value = app.Player2EditField_2.Value - 1;
                    end
                case "downarrow"                                                                               % For the arrow down and s, decelerate the jets
                    app.jetVelocity(2) = app.jetVelocity(2)+0.5*log2(app.jetVelocity(2)/app.jetAcceleration);   
                    if app.jetVelocity(2) < 0
                        app.jetVelocity(2) = 0;
                    end
                case "s"
                    app.jetVelocity(1) = app.jetVelocity(1)+0.5*log2(app.jetVelocity(1)/app.jetAcceleration);
                    if app.jetVelocity(1) < 0
                        app.jetVelocity(1) = 0;
                    end
                otherwise
            end
            for i = 1:2                                                     % Correct the directions if a 5 or 0 is present
            if app.jetDirections(i) == 0
                app.jetDirections(i) = 4;
            end
            if app.jetDirections(i) == 5
                app.jetDirections(i) = 1;
            end
            end
        end

        % Button pushed function: STOPButton
        function STOPButtonPushed(app, event)
            app.run = 0;                                                    % Break the gameloop
        end

        % Button pushed function: CLOSEButton
        function CLOSEButtonPushed(app, event)
            if(app.run == 0)                                                % Only press close when the game is not running
            close(app.UIFigure);                                            % Close the game
            end
        end

        % Button pushed function: RESETButton
        function RESETButtonPushed(app, event)
            if(app.run == 0)                                                % Only press reset when the game is not running
            reset(app);
            end
        end

        % Button pushed function: STARTButton
        function STARTButtonPushed(app, event)
            if(app.run == 0)                                                % Only press the start when the game is not running
            app.jetCoordinates = [2.5 2.5; 52.5 42.5];                      % Initial positions and directions
            app.jetDirections = [1 3];
            app.polyJet1 = jetCreate(app,app.jetCoordinates(1,:),app.jetDirections(1));
            app.polyJet2 = jetCreate(app,app.jetCoordinates(2,:),app.jetDirections(2));
            app.timer = tic;                                                % Start the timer
            if app.NormalButton.Value == 1
                app.Player1EditField_3.Value = 50;
                app.Player1EditField_2.Value = 10;
            end
            if app.NormalButton_2.Value == 1
                app.Player2EditField_3.Value = 50;
                app.Player2EditField_2.Value = 10;
            end
            if app.SolarButton.Value == 1
                app.Player1EditField_3.Value = 25;
                app.Player1EditField_2.Value = 10;
            end
            if app.SolarButton_2.Value == 1
                app.Player2EditField_3.Value = 25;
                app.Player2EditField_2.Value = 10;
            end
            if app.ImmobileButton.Value == 1
                app.Player1EditField_3.Value = 1;
                app.Player1EditField_2.Value = 99;
            end
            if app.ImmobileButton_2.Value == 1
                app.Player2EditField_3.Value = 1;
                app.Player2EditField_2.Value = 99;
            end
            gridUpdate(app);
            app.run = 1;
            game(app);                                                      % Run the gameloop
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.KeyPressFcn = createCallbackFcn(app, @KeyPressFcn, true);
            app.GridAxes = uiaxes(app.UIFigure);
            zlabel(app.GridAxes, 'Z')
            app.GridAxes.XLim = [0 55];
            app.GridAxes.YLim = [0 45];
            app.GridAxes.XTick = [1 2 3 4 5 6 7 8 9 10 11 12 13 14 15 16 17 18 19 20 21 22 23 24 25 26 27 28 29 30 31 32 33 34 35 36 37 38 39 40 41 42 43 44 45 46 47 48 49 50 51 52 53 54];
            app.GridAxes.XTickLabel = '';
            app.GridAxes.YTick = [1 2 3 4 5 6 7 8 9 10 11 12 13 14 15 16 17 18 19 20 21 22 23 24 25 26 27 28 29 30 31 32 33 34 35 36 37 38 39 40 41 42 43 44];
            app.GridAxes.YTickLabel = '';
            app.STOPButton = uibutton(app.UIFigure, 'push');
            app.STOPButton.ButtonPushedFcn = createCallbackFcn(app, @STOPButtonPushed, true);
            app.STOPButton.Text = 'STOP';
            app.CLOSEButton = uibutton(app.UIFigure, 'push');
            app.CLOSEButton.ButtonPushedFcn = createCallbackFcn(app, @CLOSEButtonPushed, true);
            app.CLOSEButton.Text = 'CLOSE';
            app.RESETButton = uibutton(app.UIFigure, 'push');
            app.RESETButton.ButtonPushedFcn = createCallbackFcn(app, @RESETButtonPushed, true);
            app.RESETButton.Text = 'RESET';
            app.STARTButton = uibutton(app.UIFigure, 'push');
            app.STARTButton.ButtonPushedFcn = createCallbackFcn(app, @STARTButtonPushed, true);
            app.STARTButton.Text = 'START';
            app.Player1EditFieldLabel = uilabel(app.UIFigure);
            app.Player1EditFieldLabel.Text = 'Player 1';
            app.Player1EditField = uieditfield(app.UIFigure, 'numeric');
            app.Player1EditField.ValueDisplayFormat = '%.0f';
            app.Player2EditFieldLabel = uilabel(app.UIFigure);
            app.Player2EditFieldLabel.Text = 'Player 2';
            app.Player2EditField = uieditfield(app.UIFigure, 'numeric');
            app.Player2EditField.ValueDisplayFormat = '%.0f';
            app.TimeEditFieldLabel = uilabel(app.UIFigure);
            app.TimeEditFieldLabel.Text = 'Time';
            app.TimeEditField = uieditfield(app.UIFigure, 'numeric');
            app.TimeEditField.ValueDisplayFormat = '%.0f';
            app.TimeEditField.Value = 30;
            app.TextArea = uitextarea(app.UIFigure);
            app.TextArea.Value = {'Jet Fighter!!!'};
            app.ScoreLabel = uilabel(app.UIFigure);
            app.ScoreLabel.Text = 'Score';
            app.Player1EditField_2Label = uilabel(app.UIFigure);
            app.Player1EditField_2Label.Text = 'Player 1';
            app.Player1EditField_2 = uieditfield(app.UIFigure, 'numeric');
            app.Player1EditField_2.ValueDisplayFormat = '%.0f';
            app.Player1EditField_2.Value = 10;
            app.BulletsLabel = uilabel(app.UIFigure);
            app.BulletsLabel.Text = 'Bullets';
            app.Player2EditField_2Label = uilabel(app.UIFigure);
            app.Player2EditField_2Label.Text = 'Player 2';
            app.Player2EditField_2 = uieditfield(app.UIFigure, 'numeric');
            app.Player2EditField_2.ValueDisplayFormat = '%.0f';
            app.Player2EditField_2.Value = 10;
            app.FuelLabel = uilabel(app.UIFigure);
            app.FuelLabel.Text = 'Fuel';
            app.Player1EditField_3Label = uilabel(app.UIFigure);
            app.Player1EditField_3Label.Text = 'Player 1';
            app.Player1EditField_3 = uieditfield(app.UIFigure, 'numeric');
            app.Player1EditField_3.ValueDisplayFormat = '%.0f';
            app.Player1EditField_3.Value = 50;
            app.Player2EditField_3Label = uilabel(app.UIFigure);
            app.Player2EditField_3Label.Text = 'Player 2';
            app.Player2EditField_3 = uieditfield(app.UIFigure, 'numeric');
            app.Player2EditField_3.ValueDisplayFormat = '%.0f';
            app.Player2EditField_3.Value = 50;
            app.AccelerateLabel = uilabel(app.UIFigure);
            app.AccelerateLabel.Text = 'Accelerate';
            app.DecelerateLabel = uilabel(app.UIFigure);
            app.DecelerateLabel.Text = 'Decelerate';
            app.RotateLabel = uilabel(app.UIFigure);
            app.RotateLabel.Text = 'Rotate';
            app.FireLabel = uilabel(app.UIFigure);
            app.FireLabel.Text = 'Fire';
            app.WLabel = uilabel(app.UIFigure);
            app.WLabel.Text = 'W';
            app.UpLabel = uilabel(app.UIFigure);
            app.UpLabel.Text = 'Up';
            app.SLabel = uilabel(app.UIFigure);
            app.SLabel.Text = 'S';
            app.DownLabel = uilabel(app.UIFigure);
            app.DownLabel.Text = 'Down';
            app.ADLabel = uilabel(app.UIFigure);
            app.ADLabel.Text = 'A/D';
            app.MLabel = uilabel(app.UIFigure);
            app.MLabel.Text = 'M';
            app.LeftRightLabel = uilabel(app.UIFigure);
            app.LeftRightLabel.Text = 'Left/Right';
            app.VLabel = uilabel(app.UIFigure);
            app.VLabel.Text = 'V';
            app.DifficultyDropDownLabel = uilabel(app.UIFigure);
            app.DifficultyDropDownLabel.Text = 'Difficulty:';
            app.DifficultyDropDown = uidropdown(app.UIFigure);
            app.DifficultyDropDown.Items = {'Normal Mode', 'Hard Mode', 'Very Hard Mode'};
            app.DifficultyDropDown.Value = 'Normal Mode';
            app.Player1JetSelectButtonGroup = uibuttongroup(app.UIFigure);
            app.Player1JetSelectButtonGroup.Title = 'Player 1 Jet Select';
            app.NormalButton = uiradiobutton(app.Player1JetSelectButtonGroup);
            app.NormalButton.Text = 'Normal';
            app.NormalButton.Value = true;
            app.SolarButton = uiradiobutton(app.Player1JetSelectButtonGroup);
            app.SolarButton.Text = 'Solar';
            app.ImmobileButton = uiradiobutton(app.Player1JetSelectButtonGroup);
            app.ImmobileButton.Text = 'Immobile';
            app.Player2JetSelectButtonGroup = uibuttongroup(app.UIFigure);
            app.Player2JetSelectButtonGroup.Title = 'Player 2 Jet Select';
            app.NormalButton_2 = uiradiobutton(app.Player2JetSelectButtonGroup);
            app.NormalButton_2.Text = 'Normal';
            app.NormalButton_2.Value = true;
            app.SolarButton_2 = uiradiobutton(app.Player2JetSelectButtonGroup);
            app.SolarButton_2.Text = 'Solar';
            app.ImmobileButton_2 = uiradiobutton(app.Player2JetSelectButtonGroup);
            app.ImmobileButton_2.Text = 'Immobile';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = MatlabMidtermV2

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end