classdef app1 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure        matlab.ui.Figure
        EditField       matlab.ui.control.NumericEditField
        EditFieldLabel  matlab.ui.control.Label
        Button_4        matlab.ui.control.Button
        Button_3        matlab.ui.control.Button
        Button_2        matlab.ui.control.Button
        Button          matlab.ui.control.Button
        GridAxes        matlab.ui.control.UIAxes
    end

    
    properties (Access = public)
    end
    
    properties (Access = private)
        coordinates % Description
        direction % Description
    end
    
    methods (Access = private)

        
        function gameLoop(app)
%             for  x = .5:1:54.5
%             coordinates = [x 20.5];
%             direction = "r";
%             jet1 = jetCreate(app,coordinates,direction);
%             plot(app.GridAxes,jet1(:,1),jet1(:,2),"square",MarkerFaceColor="Black",MarkerSize=16,MarkerEdgeColor="Black");
%             pause(.25);
%             end
        end


        function jetPoints = jetCreate(app,pos,dir)
            switch dir
                case 1
                    jetPoints = [pos(1) pos(2); pos(1) pos(2)+1; pos(1)-1 pos(2)-1; pos(1) pos(2)-1; pos(1)+1 pos(2)-1];
                case 3
                    jetPoints = [pos(1) pos(2); pos(1) pos(2)-1; pos(1)+1 pos(2)+1; pos(1) pos(2)+1; pos(1)-1 pos(2)+1];
                case 4
                    jetPoints = [pos(1) pos(2); pos(1)-1 pos(2); pos(1)+1 pos(2)-1; pos(1)+1 pos(2); pos(1)+1 pos(2)+1];
                case 2  
                    jetPoints = [pos(1) pos(2); pos(1)+1 pos(2); pos(1)-1 pos(2)-1; pos(1)-1 pos(2); pos(1)-1 pos(2)+1];
                otherwise

            end

        end
        
        function posUpdate(app)
            jet1 = jetCreate(app,app.coordinates,app.direction);
            plot(app.GridAxes,jet1(:,1),jet1(:,2),"square",MarkerFaceColor="Black",MarkerSize=16,MarkerEdgeColor="Black");
            
        end
    end
  
    
    methods (Access = public)
        
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.coordinates = [27.5 21.5];
            app.direction = 1;
            jet = jetCreate(app,app.coordinates,app.direction);
            plot(app.GridAxes,jet(:,1),jet(:,2),"square",MarkerFaceColor="Black",MarkerSize=16,MarkerEdgeColor="Black");
        end

        % Button pushed function: Button
        function ButtonPushed(app, event)
            gameLoop(app);
            
        end

        % Key press function: UIFigure
        function KeyPressFcn(app, event)
            key = event.Key;
            switch key
                case "w"
                    switch app.direction
                        case 1
                            app.coordinates(2) = app.coordinates(2) + 1;
                        case 3
                            app.coordinates(2) = app.coordinates(2) - 1;
                        case 2
                            app.coordinates(1) = app.coordinates(1) + 1;
                        case 4
                            app.coordinates(1) = app.coordinates(1) - 1;
                    end
                case "d"
                    app.direction = app.direction + 1;
                case "a"
                    app.direction = app.direction - 1;
                otherwise
            end
            if app.direction == 0
                app.direction = 4;
            end
            if app.direction == 5
                app.direction = 1;
            end
            posUpdate(app);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.KeyPressFcn = createCallbackFcn(app, @KeyPressFcn, true);
            app.GridAxes = uiaxes(app.UIFigure);
            zlabel(app.GridAxes, 'Z')
            app.GridAxes.XLim = [0 55];
            app.GridAxes.YLim = [0 45];
            app.GridAxes.XTick = [1 2 3 4 5 6 7 8 9 10 11 12 13 14 15 16 17 18 19 20 21 22 23 24 25 26 27 28 29 30 31 32 33 34 35 36 37 38 39 40 41 42 43 44 45 46 47 48 49 50 51 52 53 54];
            app.GridAxes.XTickLabel = '';
            app.GridAxes.YTick = [1 2 3 4 5 6 7 8 9 10 11 12 13 14 15 16 17 18 19 20 21 22 23 24 25 26 27 28 29 30 31 32 33 34 35 36 37 38 39 40 41 42 43 44];
            app.GridAxes.YTickLabel = '';
            app.Button = uibutton(app.UIFigure, 'push');
            app.Button.ButtonPushedFcn = createCallbackFcn(app, @ButtonPushed, true);
            app.Button_2 = uibutton(app.UIFigure, 'push');
            app.Button_3 = uibutton(app.UIFigure, 'push');
            app.Button_4 = uibutton(app.UIFigure, 'push');
            app.EditFieldLabel = uilabel(app.UIFigure);
            app.EditFieldLabel.Text = 'Edit Field';
            app.EditField = uieditfield(app.UIFigure, 'numeric');
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = app1

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end